classdef imageClassifierVer2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Cat2Count                       matlab.ui.control.Label
        Cat1Count                       matlab.ui.control.Label
        UnrecordButton                  matlab.ui.control.Button
        NotRecLabel                     matlab.ui.control.Label
        ToggleBoxButton                 matlab.ui.control.Button
        YBoxEditField                   matlab.ui.control.NumericEditField
        YBoxEditFieldLabel              matlab.ui.control.Label
        XBoxEditField                   matlab.ui.control.NumericEditField
        XBoxEditFieldLabel              matlab.ui.control.Label
        KeptRefFramesDropDown           matlab.ui.control.DropDown
        KeptRefFramesDropDownLabel      matlab.ui.control.Label
        RemoveRefFrameButton            matlab.ui.control.Button
        KeepRefFrameButton              matlab.ui.control.Button
        ReferenceFrameEditField         matlab.ui.control.NumericEditField
        ReferenceFrameEditFieldLabel    matlab.ui.control.Label
        NumberofFramesSkippedEditField  matlab.ui.control.NumericEditField
        NumberofFramesSkippedEditFieldLabel  matlab.ui.control.Label
        RecordCategorySwitch            matlab.ui.control.ToggleSwitch
        RecordCategorySwitchLabel       matlab.ui.control.Label
        FileNameLabel                   matlab.ui.control.Label
        TimeXYLabel                     matlab.ui.control.Label
        FrameXYLabel                    matlab.ui.control.Label
        GotoPreviousOutlierButton       matlab.ui.control.Button
        GotoNextOultierButton           matlab.ui.control.Button
        NumberofOutlierFramesEditField  matlab.ui.control.NumericEditField
        NumberofOutlierFramesEditFieldLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        GoBack1SecButton                matlab.ui.control.Button
        PreviousFrameButton             matlab.ui.control.Button
        GoForward1SecButton             matlab.ui.control.Button
        NextFrameButton                 matlab.ui.control.Button
        TimeSlider                      matlab.ui.control.Slider
        TimeSliderLabel                 matlab.ui.control.Label
        ImageCategoryButtonGroup        matlab.ui.container.ButtonGroup
        Cat2Button                      matlab.ui.control.RadioButton
        Cat1Button                      matlab.ui.control.RadioButton
        LoadVideoButton                 matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fileInfo % path and file name information for the loaded video
        currentFrameNum % Number of the current frame being displayed
        hFrame % The image handle for the displayed image
        idOutFrames % A list of frame numbers for outlier frames
        video % Matrix of video frames
        videoInfo % Information about the video
        currentTime % The time of the current frame in seconds
        currentOutlier % The current outlier frame
        frameCats % Category assignments (0: uncategorized, 1: category 1, 2: category 2)
        categories % Category names for the categories in frameCats
        fLineHandles % Handles to the lines that mark reviewed frames
        RefFrame % Frame number for reference subtraction
        subVideo % Matrix of reference frame subtracted video frames
        hSubFrame % The image handle for the displayed reference subtracted image
        refFrameCats % Reference status of each frame (0: not reference frame, 1: reference frame)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % Save the categories and categorized frames
            imgCats = app.frameCats;
            frames = app.video(:,:,app.frameCats > 0);
            refFrames = app.video(:,:,app.refFrameCats == 1);
            cropCoords = [app.XBoxEditField.Value,app.YBoxEditField.Value];
            save([app.fileInfo{2},'images.mat'],'frames','imgCats','refFrames','cropCoords')
            
            % Tell the user the data have been saved
            f = msgbox('The data have been saved.');
            uiwait(f)
            
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
            
            % Load the video file
            [file,path,~] = uigetfile;
            
            % Update the file name label and record the info
            if length(path) > 60
                app.FileNameLabel.Text = ['...',path((end - 60):end),file];
            else
                app.FileNameLabel.Text = [path,file];
            end
            app.fileInfo = {file,path};
            
            % Tell the user to be patient
            f = msgbox('Loading and flatening video. This may take a few minutes.');
            
            % Load the video frames
            v = VideoReader([path,file]);
            
            % Read the frames
            app.video = read(v,[1,inf]);
            
            % Remove excess colors
            app.video = squeeze(app.video(:,:,1,:));
            
            % Decimate the video frames
            app.video = app.video(:,:,1:app.NumberofFramesSkippedEditField.Value:end);

            % Set the reference frame number
            app.RefFrame = 1;

            % Generate the reference subtracted video frame images
            app.subVideo = uint8(abs(repmat(double(app.video(:,:,app.RefFrame)),[1,1,size(app.video,3)]) - double(app.video)));

            % Generate a list of reference frames
            app.refFrameCats = zeros([size(app.video,3),1]);
            app.refFrameCats(1) = 1;

            % Identify outlier frames
            try
                close(f)
            end
            aveFrame = mean(app.video,3);
            totalFrameDif = NaN([size(app.video,3),1]);
            f = waitbar(0,'Looking for outlier frames.');
            for iFrame = 1:size(app.video,3)
                waitbar(iFrame/size(app.video,3),f);
                totalFrameDif(iFrame) = sum(sum(abs(double(app.video(:,:,iFrame)) - aveFrame)));
            end
            temp = sort(totalFrameDif,'descend');
            app.NumberofOutlierFramesEditField.Value = round(app.NumberofOutlierFramesEditField.Value);
            if app.NumberofOutlierFramesEditField.Value < 0
                app.NumberofOutlierFramesEditField.Value = 0;
            end
            app.idOutFrames = find(totalFrameDif >= temp(app.NumberofOutlierFramesEditField.Value));
            app.currentOutlier = 0;
            close(f)
            
            % Get information about the video
            app.videoInfo = [size(app.video,3),...
                (size(app.video,3) - 1)*(app.NumberofFramesSkippedEditField.Value/v.FrameRate),...
                (app.NumberofFramesSkippedEditField.Value/v.FrameRate)];
            
            % Put the time limits in the slider
            app.TimeSlider.Limits = [0,app.videoInfo(2)];
            
            % Put the time limits in the category graph
            xlim(app.UIAxes2,[0,app.videoInfo(2)])
            
            % Make a record of the categories
            app.frameCats = zeros([app.videoInfo(1),1]);
            app.categories = {app.Cat1Button.Text,app.Cat2Button.Text};
            
%             Y = zeros([24,32,41602]);
%             for i = 1:41602
%                 Y(:,:,i) = imresize(video(:,:,i),0.1);
%             end
%             Ysmall = Y(:,:,1:10:end);
%             Ydist = pdist(reshape(Ysmall,[24*32,4161])');
%             Z = linkage(Ydist);
%             T = cluster(Z,'MaxClust',40); % This might work well for
%             outlier frame detection.
            
            
            % Display the first frame
            app.currentFrameNum = 1;
            app.currentTime = 0;
            frame = app.video(:,:,app.currentFrameNum);
            frameSize = size(frame);
            xdata = [1 frameSize(2)];
            ydata = [1 frameSize(1)];
            app.hFrame = image(xdata,ydata,frame, ...
                'Parent', app.UIAxes, ...
                'CDataMapping', 'direct');
            set(app.UIAxes, ...
                'XLim',xdata,...
                'YLim',ydata,...
                'DataAspectRatio',[1,1,1]);
            colormap(app.UIAxes,'gray')
            frame = app.subVideo(:,:,app.currentFrameNum);
            frameSize = size(frame);
            xdata = [1 frameSize(2)];
            ydata = [1 frameSize(1)];
            app.hSubFrame = image(xdata,ydata,frame, ...
                'Parent', app.UIAxes_2, ...
                'CDataMapping', 'direct');
            set(app.UIAxes_2, ...
                'XLim',xdata,...
                'YLim',ydata,...
                'DataAspectRatio',[1,1,1]);
            colormap(app.UIAxes_2,'gray')
            
            % Update the frame and time display
            app.FrameXYLabel.Text = ['Frame ',num2str(app.currentFrameNum),' (',num2str(app.videoInfo(1)),')'];
            app.TimeXYLabel.Text = ['Time ',num2str(app.currentTime,4),' sec (',num2str(app.videoInfo(2),4),' sec)'];
            
            % Update the time slider
            app.TimeSlider.Value = app.currentTime;
            
            % Make all the frame lines in the category graph
            app.fLineHandles = zeros([1,app.videoInfo(1)]);
            hold(app.UIAxes2,'on')
            for iFrame = 1:app.videoInfo(1)
                app.fLineHandles(iFrame) = line(app.UIAxes2,[(iFrame -1)*app.videoInfo(3),(iFrame -1)*app.videoInfo(3)],...
                    [0,1],'color','w','linewidth',0.1);
            end
            
            % Tell the user the video has been loaded
            f = msgbox('Video loading finished. Proceed with image categorization.');
            uiwait(f)
            
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            
            % Display the next frame
            if app.currentFrameNum < size(app.video,3)
                
                % Advance the frame number
                app.currentFrameNum = app.currentFrameNum + 1;
                app.currentTime = (app.currentFrameNum - 1)*app.videoInfo(3);
                
                % Display the frame
                frame = app.video(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                frame = app.subVideo(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hSubFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes_2, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes_2, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                colormap(app.UIAxes_2,'gray')

                % Update the frame and time display
                app.FrameXYLabel.Text = ['Frame ',num2str(app.currentFrameNum),' (',num2str(app.videoInfo(1)),')'];
                app.TimeXYLabel.Text = ['Time ',num2str(app.currentTime,4),' sec (',num2str(app.videoInfo(2),4),' sec)'];
                
                % Update the time slider
                app.TimeSlider.Value = app.currentTime;
                
                % Update the category button if a category has been
                % recorded for this frame
                if app.frameCats(app.currentFrameNum) == 1
                    app.Cat1Button.Value = true;
                    app.Cat2Button.Value = false;
                elseif app.frameCats(app.currentFrameNum) == 2
                    app.Cat1Button.Value = false;
                    app.Cat2Button.Value = true;
                end
                
                % Record the category for this frame, if we are recording
                if strcmp(app.RecordCategorySwitch.Value,'On')
                    if strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{1})
                        app.frameCats(app.currentFrameNum) = 1;
                        set(app.fLineHandles(app.currentFrameNum),'color','r')
                    elseif strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{2})
                        app.frameCats(app.currentFrameNum) = 2;
                        set(app.fLineHandles(app.currentFrameNum),'color','b')
                    else
                        error('Not enough categories.')
                    end
                end

                % Update the frame record status indicator
                if app.frameCats(app.currentFrameNum) == 0
                    app.NotRecLabel.Text = 'Not Rec.';
                else
                    app.NotRecLabel.Text = 'Rec.';
                end

                % Update the number of categorized frames for each category
                app.Cat1Count.Text = ['(',num2str(nnz(app.frameCats == 1)),')'];
                app.Cat2Count.Text = ['(',num2str(nnz(app.frameCats == 2)),')'];

            end
            
        end

        % Button pushed function: PreviousFrameButton
        function PreviousFrameButtonPushed(app, event)
            
            % Display the previous frame
            if app.currentFrameNum > 1
                
                % Go back one frame number
                app.currentFrameNum = app.currentFrameNum - 1;
                app.currentTime = (app.currentFrameNum - 1)*app.videoInfo(3);
                
                % Display the frame
                frame = app.video(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                frame = app.subVideo(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hSubFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes_2, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes_2, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                colormap(app.UIAxes_2,'gray')
                
                % Update the frame and time display
                app.FrameXYLabel.Text = ['Frame ',num2str(app.currentFrameNum),' (',num2str(app.videoInfo(1)),')'];
                app.TimeXYLabel.Text = ['Time ',num2str(app.currentTime,4),' sec (',num2str(app.videoInfo(2),4),' sec)'];
                
                % Update the time slider
                app.TimeSlider.Value = app.currentTime;
                
                % Update the category button if a category has been
                % recorded for this frame
                if app.frameCats(app.currentFrameNum) == 1
                    app.Cat1Button.Value = true;
                    app.Cat2Button.Value = false;
                elseif app.frameCats(app.currentFrameNum) == 2
                    app.Cat1Button.Value = false;
                    app.Cat2Button.Value = true;
                end
                
                % Record the category for this frame, if we are recording
                if strcmp(app.RecordCategorySwitch.Value,'On')
                    if strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{1})
                        app.frameCats(app.currentFrameNum) = 1;
                        set(app.fLineHandles(app.currentFrameNum),'color','r')
                    elseif strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{2})
                        app.frameCats(app.currentFrameNum) = 2;
                        set(app.fLineHandles(app.currentFrameNum),'color','b')
                    else
                        error('Not enough categories.')
                    end
                end

                % Update the frame record status indicator
                if app.frameCats(app.currentFrameNum) == 0
                    app.NotRecLabel.Text = 'Not Rec.';
                else
                    app.NotRecLabel.Text = 'Rec.';
                end

                % Update the number of categorized frames for each category
                app.Cat1Count.Text = ['(',num2str(nnz(app.frameCats == 1)),')'];
                app.Cat2Count.Text = ['(',num2str(nnz(app.frameCats == 2)),')'];

            end
            
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            
            % Find the nearest frame to this time
            app.currentFrameNum = round(app.TimeSlider.Value/app.videoInfo(3)) + 1;
            app.currentTime = (app.currentFrameNum - 1)*app.videoInfo(3);
            
            % Display the frame
            frame = app.video(:,:,app.currentFrameNum);
            frameSize = size(frame);
            xdata = [1 frameSize(2)];
            ydata = [1 frameSize(1)];
            app.hFrame = image(xdata,ydata,frame, ...
                'Parent', app.UIAxes, ...
                'CDataMapping', 'direct');
            set(app.UIAxes, ...
                'XLim',xdata,...
                'YLim',ydata,...
                'DataAspectRatio',[1,1,1]);
            frame = app.subVideo(:,:,app.currentFrameNum);
            frameSize = size(frame);
            xdata = [1 frameSize(2)];
            ydata = [1 frameSize(1)];
            app.hSubFrame = image(xdata,ydata,frame, ...
                'Parent', app.UIAxes_2, ...
                'CDataMapping', 'direct');
            set(app.UIAxes_2, ...
                'XLim',xdata,...
                'YLim',ydata,...
                'DataAspectRatio',[1,1,1]);
            colormap(app.UIAxes_2,'gray')
            
            % Update the frame and time display
            app.FrameXYLabel.Text = ['Frame ',num2str(app.currentFrameNum),' (',num2str(app.videoInfo(1)),')'];
            app.TimeXYLabel.Text = ['Time ',num2str(app.currentTime,4),' sec (',num2str(app.videoInfo(2),4),' sec)'];
            
            % Update the time slider
            app.TimeSlider.Value = app.currentTime;
            
            % Update the category button if a category has been
            % recorded for this frame
            if app.frameCats(app.currentFrameNum) == 1
                app.Cat1Button.Value = true;
                app.Cat2Button.Value = false;
            elseif app.frameCats(app.currentFrameNum) == 2
                app.Cat1Button.Value = false;
                app.Cat2Button.Value = true;
            end
            
            % Record the category for this frame, if we are recording
            if strcmp(app.RecordCategorySwitch.Value,'On')
                if strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{1})
                    app.frameCats(app.currentFrameNum) = 1;
                    set(app.fLineHandles(app.currentFrameNum),'color','r')
                elseif strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{2})
                    app.frameCats(app.currentFrameNum) = 2;
                    set(app.fLineHandles(app.currentFrameNum),'color','b')
                else
                    error('Not enough categories.')
                end
            end

            % Update the frame record status indicator
            if app.frameCats(app.currentFrameNum) == 0
                app.NotRecLabel.Text = 'Not Rec.';
            else
                app.NotRecLabel.Text = 'Rec.';
            end

            % Update the number of categorized frames for each category
            app.Cat1Count.Text = ['(',num2str(nnz(app.frameCats == 1)),')'];
            app.Cat2Count.Text = ['(',num2str(nnz(app.frameCats == 2)),')'];

        end

        % Button pushed function: GoForward1SecButton
        function GoForward1SecButtonPushed(app, event)
            
            if (app.currentTime + 1 + app.videoInfo(3)) <= app.videoInfo(2)
                
                % Find the nearest frame to this time
                app.currentFrameNum = round((app.currentTime + 1)/app.videoInfo(3)) + 1;
                app.currentTime = (app.currentFrameNum - 1)*app.videoInfo(3);
                
                % Display the frame
                frame = app.video(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                frame = app.subVideo(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hSubFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes_2, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes_2, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                colormap(app.UIAxes_2,'gray')
                
                % Update the frame and time display
                app.FrameXYLabel.Text = ['Frame ',num2str(app.currentFrameNum),' (',num2str(app.videoInfo(1)),')'];
                app.TimeXYLabel.Text = ['Time ',num2str(app.currentTime,4),' sec (',num2str(app.videoInfo(2),4),' sec)'];
                
                % Update the time slider
                app.TimeSlider.Value = app.currentTime;
                
                % Update the category button if a category has been
                % recorded for this frame
                if app.frameCats(app.currentFrameNum) == 1
                    app.Cat1Button.Value = true;
                    app.Cat2Button.Value = false;
                elseif app.frameCats(app.currentFrameNum) == 2
                    app.Cat1Button.Value = false;
                    app.Cat2Button.Value = true;
                end
                
                % Record the category for this frame, if we are recording
                if strcmp(app.RecordCategorySwitch.Value,'On')
                    if strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{1})
                        app.frameCats(app.currentFrameNum) = 1;
                        set(app.fLineHandles(app.currentFrameNum),'color','r')
                    elseif strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{2})
                        app.frameCats(app.currentFrameNum) = 2;
                        set(app.fLineHandles(app.currentFrameNum),'color','b')
                    else
                        error('Not enough categories.')
                    end
                end

                % Update the frame record status indicator
                if app.frameCats(app.currentFrameNum) == 0
                    app.NotRecLabel.Text = 'Not Rec.';
                else
                    app.NotRecLabel.Text = 'Rec.';
                end

                % Update the number of categorized frames for each category
                app.Cat1Count.Text = ['(',num2str(nnz(app.frameCats == 1)),')'];
                app.Cat2Count.Text = ['(',num2str(nnz(app.frameCats == 2)),')'];
                
            end
            
        end

        % Button pushed function: GoBack1SecButton
        function GoBack1SecButtonPushed(app, event)
            
            if (app.currentTime - 1 - app.videoInfo(3)) >= 0
                
                % Find the nearest frame to this time
                app.currentFrameNum = round((app.currentTime - 1)/app.videoInfo(3)) + 1;
                app.currentTime = (app.currentFrameNum - 1)*app.videoInfo(3);
                
                % Display the frame
                frame = app.video(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                frame = app.subVideo(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hSubFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes_2, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes_2, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                colormap(app.UIAxes_2,'gray')
                
                % Update the frame and time display
                app.FrameXYLabel.Text = ['Frame ',num2str(app.currentFrameNum),' (',num2str(app.videoInfo(1)),')'];
                app.TimeXYLabel.Text = ['Time ',num2str(app.currentTime,4),' sec (',num2str(app.videoInfo(2),4),' sec)'];
                
                % Update the time slider
                app.TimeSlider.Value = app.currentTime;
                
                % Update the category button if a category has been
                % recorded for this frame
                if app.frameCats(app.currentFrameNum) == 1
                    app.Cat1Button.Value = true;
                    app.Cat2Button.Value = false;
                elseif app.frameCats(app.currentFrameNum) == 2
                    app.Cat1Button.Value = false;
                    app.Cat2Button.Value = true;
                end
                
                % Record the category for this frame, if we are recording
                if strcmp(app.RecordCategorySwitch.Value,'On')
                    if strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{1})
                        app.frameCats(app.currentFrameNum) = 1;
                        set(app.fLineHandles(app.currentFrameNum),'color','r')
                    elseif strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{2})
                        app.frameCats(app.currentFrameNum) = 2;
                        set(app.fLineHandles(app.currentFrameNum),'color','b')
                    else
                        error('Not enough categories.')
                    end
                end

                % Update the frame record status indicator
                if app.frameCats(app.currentFrameNum) == 0
                    app.NotRecLabel.Text = 'Not Rec.';
                else
                    app.NotRecLabel.Text = 'Rec.';
                end

                % Update the number of categorized frames for each category
                app.Cat1Count.Text = ['(',num2str(nnz(app.frameCats == 1)),')'];
                app.Cat2Count.Text = ['(',num2str(nnz(app.frameCats == 2)),')'];
                
            end
            
        end

        % Button pushed function: GotoNextOultierButton
        function GotoNextOultierButtonPushed(app, event)
            
            if app.currentOutlier == 0
                
                % Go to the first outlier
                app.currentOutlier = 1;
                app.currentFrameNum = app.idOutFrames(app.currentOutlier);
                app.currentTime = (app.currentFrameNum - 1)*app.videoInfo(3);
                
                % Display the frame
                frame = app.video(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                frame = app.subVideo(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hSubFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes_2, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes_2, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                colormap(app.UIAxes_2,'gray')
                
                % Update the frame and time display
                app.FrameXYLabel.Text = ['Frame ',num2str(app.currentFrameNum),' (',num2str(app.videoInfo(1)),')'];
                app.TimeXYLabel.Text = ['Time ',num2str(app.currentTime,4),' sec (',num2str(app.videoInfo(2),4),' sec)'];
                
                % Update the time slider
                app.TimeSlider.Value = app.currentTime;
                
                % Update the category button if a category has been
                % recorded for this frame
                if app.frameCats(app.currentFrameNum) == 1
                    app.Cat1Button.Value = true;
                    app.Cat2Button.Value = false;
                elseif app.frameCats(app.currentFrameNum) == 2
                    app.Cat1Button.Value = false;
                    app.Cat2Button.Value = true;
                end
                
                % Record the category for this frame, if we are recording
                if strcmp(app.RecordCategorySwitch.Value,'On')
                    if strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{1})
                        app.frameCats(app.currentFrameNum) = 1;
                        set(app.fLineHandles(app.currentFrameNum),'color','r')
                    elseif strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{2})
                        app.frameCats(app.currentFrameNum) = 2;
                        set(app.fLineHandles(app.currentFrameNum),'color','b')
                    else
                        error('Not enough categories.')
                    end
                end

                % Update the frame record status indicator
                if app.frameCats(app.currentFrameNum) == 0
                    app.NotRecLabel.Text = 'Not Rec.';
                else
                    app.NotRecLabel.Text = 'Rec.';
                end

                % Update the number of categorized frames for each category
                app.Cat1Count.Text = ['(',num2str(nnz(app.frameCats == 1)),')'];
                app.Cat2Count.Text = ['(',num2str(nnz(app.frameCats == 2)),')'];
            
            elseif app.currentOutlier < app.NumberofOutlierFramesEditField.Value
                
                % Find the nearest frame to this time
                app.currentOutlier = app.currentOutlier + 1;
                app.currentFrameNum = app.idOutFrames(app.currentOutlier);
                app.currentTime = (app.currentFrameNum - 1)*app.videoInfo(3);
                
                % Display the frame
                frame = app.video(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                frame = app.subVideo(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hSubFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes_2, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes_2, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                colormap(app.UIAxes_2,'gray')
                
                % Update the frame and time display
                app.FrameXYLabel.Text = ['Frame ',num2str(app.currentFrameNum),' (',num2str(app.videoInfo(1)),')'];
                app.TimeXYLabel.Text = ['Time ',num2str(app.currentTime,4),' sec (',num2str(app.videoInfo(2),4),' sec)'];
                
                % Update the time slider
                app.TimeSlider.Value = app.currentTime;
                
                % Update the category button if a category has been
                % recorded for this frame
                if app.frameCats(app.currentFrameNum) == 1
                    app.Cat1Button.Value = true;
                    app.Cat2Button.Value = false;
                elseif app.frameCats(app.currentFrameNum) == 2
                    app.Cat1Button.Value = false;
                    app.Cat2Button.Value = true;
                end
                
                % Record the category for this frame, if we are recording
                if strcmp(app.RecordCategorySwitch.Value,'On')
                    if strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{1})
                        app.frameCats(app.currentFrameNum) = 1;
                        set(app.fLineHandles(app.currentFrameNum),'color','r')
                    elseif strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{2})
                        app.frameCats(app.currentFrameNum) = 2;
                        set(app.fLineHandles(app.currentFrameNum),'color','b')
                    else
                        error('Not enough categories.')
                    end
                end

                % Update the frame record status indicator
                if app.frameCats(app.currentFrameNum) == 0
                    app.NotRecLabel.Text = 'Not Rec.';
                else
                    app.NotRecLabel.Text = 'Rec.';
                end

                % Update the number of categorized frames for each category
                app.Cat1Count.Text = ['(',num2str(nnz(app.frameCats == 1)),')'];
                app.Cat2Count.Text = ['(',num2str(nnz(app.frameCats == 2)),')'];
                
            end
            
        end

        % Button pushed function: GotoPreviousOutlierButton
        function GotoPreviousOutlierButtonPushed(app, event)
            
            if app.currentOutlier > 1
                
                % Find the nearest frame to this time
                app.currentOutlier = app.currentOutlier - 1;
                app.currentFrameNum = app.idOutFrames(app.currentOutlier);
                app.currentTime = (app.currentFrameNum - 1)*app.videoInfo(3);
                
                % Display the frame
                frame = app.video(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                frame = app.subVideo(:,:,app.currentFrameNum);
                frameSize = size(frame);
                xdata = [1 frameSize(2)];
                ydata = [1 frameSize(1)];
                app.hSubFrame = image(xdata,ydata,frame, ...
                    'Parent', app.UIAxes_2, ...
                    'CDataMapping', 'direct');
                set(app.UIAxes_2, ...
                    'XLim',xdata,...
                    'YLim',ydata,...
                    'DataAspectRatio',[1,1,1]);
                colormap(app.UIAxes_2,'gray')
                
                % Update the frame and time display
                app.FrameXYLabel.Text = ['Frame ',num2str(app.currentFrameNum),' (',num2str(app.videoInfo(1)),')'];
                app.TimeXYLabel.Text = ['Time ',num2str(app.currentTime,4),' sec (',num2str(app.videoInfo(2),4),' sec)'];
                
                % Update the time slider
                app.TimeSlider.Value = app.currentTime;
                
                % Update the category button if a category has been
                % recorded for this frame
                if app.frameCats(app.currentFrameNum) == 1
                    app.Cat1Button.Value = true;
                    app.Cat2Button.Value = false;
                elseif app.frameCats(app.currentFrameNum) == 2
                    app.Cat1Button.Value = false;
                    app.Cat2Button.Value = true;
                end
                
                % Record the category for this frame, if we are recording
                if strcmp(app.RecordCategorySwitch.Value,'On')
                    if strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{1})
                        app.frameCats(app.currentFrameNum) = 1;
                        set(app.fLineHandles(app.currentFrameNum),'color','r')
                    elseif strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{2})
                        app.frameCats(app.currentFrameNum) = 2;
                        set(app.fLineHandles(app.currentFrameNum),'color','b')
                    else
                        error('Not enough categories.')
                    end
                end

                % Update the frame record status indicator
                if app.frameCats(app.currentFrameNum) == 0
                    app.NotRecLabel.Text = 'Not Rec.';
                else
                    app.NotRecLabel.Text = 'Rec.';
                end

                % Update the number of categorized frames for each category
                app.Cat1Count.Text = ['(',num2str(nnz(app.frameCats == 1)),')'];
                app.Cat2Count.Text = ['(',num2str(nnz(app.frameCats == 2)),')'];
                
            end
            
        end

        % Selection changed function: ImageCategoryButtonGroup
        function ImageCategoryButtonGroupSelectionChanged(app, event)
            
            % Record the category for this frame, if we are recording
            if strcmp(app.RecordCategorySwitch.Value,'On')
                if strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{1})
                    app.frameCats(app.currentFrameNum) = 1;
                    set(app.fLineHandles(app.currentFrameNum),'color','r')
                elseif strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{2})
                    app.frameCats(app.currentFrameNum) = 2;
                    set(app.fLineHandles(app.currentFrameNum),'color','b')
                else
                    error('Not enough categories.')
                end
            end

            % Update the number of categorized frames for each category
            app.Cat1Count.Text = ['(',num2str(nnz(app.frameCats == 1)),')'];
            app.Cat2Count.Text = ['(',num2str(nnz(app.frameCats == 2)),')'];
            
        end

        % Value changed function: RecordCategorySwitch
        function RecordCategorySwitchValueChanged(app, event)
            
            % Record the category for this frame, if we are recording
            if strcmp(app.RecordCategorySwitch.Value,'On')
                if strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{1})
                    app.frameCats(app.currentFrameNum) = 1;
                    set(app.fLineHandles(app.currentFrameNum),'color','r')
                    app.NotRecLabel.Text = 'Rec.';
                elseif strcmp(app.ImageCategoryButtonGroup.SelectedObject.Text,app.categories{2})
                    app.frameCats(app.currentFrameNum) = 2;
                    set(app.fLineHandles(app.currentFrameNum),'color','b')
                    app.NotRecLabel.Text = 'Rec.';
                else
                    error('Not enough categories.')
                end
            end

            % Update the number of categorized frames for each category
            app.Cat1Count.Text = ['(',num2str(nnz(app.frameCats == 1)),')'];
            app.Cat2Count.Text = ['(',num2str(nnz(app.frameCats == 2)),')'];
            
        end

        % Value changed function: ReferenceFrameEditField
        function ReferenceFrameEditFieldValueChanged(app, event)

            % Update the reference frame number and make sure it is an
            % allowed value
            app.RefFrame = round(app.ReferenceFrameEditField.Value);
            if app.RefFrame < 1
                app.RefFrame = 1;
            elseif app.RefFrame > app.videoInfo(1)
                app.RefFrame = app.videoInfo(1);
            end
            app.ReferenceFrameEditField.Value = app.RefFrame;

            % Recalculate subtracted video with the new reference frame
            app.subVideo = uint8(abs(repmat(double(app.video(:,:,app.RefFrame)),[1,1,size(app.video,3)]) - double(app.video)));

            % Update the subtracted frame display
            frame = app.subVideo(:,:,app.currentFrameNum);
            frameSize = size(frame);
            xdata = [1 frameSize(2)];
            ydata = [1 frameSize(1)];
            app.hSubFrame = image(xdata,ydata,frame, ...
                'Parent', app.UIAxes_2, ...
                'CDataMapping', 'direct');
            set(app.UIAxes_2, ...
                'XLim',xdata,...
                'YLim',ydata,...
                'DataAspectRatio',[1,1,1]);
            colormap(app.UIAxes_2,'gray')
            
        end

        % Value changed function: KeptRefFramesDropDown
        function KeptRefFramesDropDownValueChanged(app, event)
            app.RefFrame = str2double(app.KeptRefFramesDropDown.Value(7:end));
            
            % Recalculate subtracted video with the new reference frame
            app.subVideo = uint8(abs(repmat(double(app.video(:,:,app.RefFrame)),[1,1,size(app.video,3)]) - double(app.video)));

            % Update the subtracted frame display
            frame = app.subVideo(:,:,app.currentFrameNum);
            frameSize = size(frame);
            xdata = [1 frameSize(2)];
            ydata = [1 frameSize(1)];
            app.hSubFrame = image(xdata,ydata,frame, ...
                'Parent', app.UIAxes_2, ...
                'CDataMapping', 'direct');
            set(app.UIAxes_2, ...
                'XLim',xdata,...
                'YLim',ydata,...
                'DataAspectRatio',[1,1,1]);
            colormap(app.UIAxes_2,'gray')
        end

        % Button pushed function: KeepRefFrameButton
        function KeepRefFrameButtonPushed(app, event)
            
            % Add this frame to the reference frame list
            app.refFrameCats(app.currentFrameNum) = 1;

            % Update the list of reference frames
            refFrames = find(app.refFrameCats == 1);
            tempList = cell([1,length(refFrames)]);
            for iFrame = 1:length(refFrames)
                tempList{iFrame} = ['Frame ',num2str(refFrames(iFrame))];
            end
            app.KeptRefFramesDropDown.Items = tempList;

        end

        % Button pushed function: RemoveRefFrameButton
        function RemoveRefFrameButtonPushed(app, event)
            
            % Remove this frame to the reference frame list
            app.refFrameCats(app.currentFrameNum) = 0;

            % Update the list of reference frames
            refFrames = find(app.refFrameCats == 1);
            tempList = cell([1,length(refFrames)]);
            for iFrame = 1:length(refFrames)
                tempList{iFrame} = ['Frame ',num2str(refFrames(iFrame))];
            end
            app.KeptRefFramesDropDown.Items = tempList;
        end

        % Button pushed function: ToggleBoxButton
        function ToggleBoxButtonPushed(app, event)
            rectangle(app.UIAxes_2,'Position',[app.XBoxEditField.Value,app.YBoxEditField.Value,100,80],'EdgeColor','r','LineWidth',1)
            rectangle(app.UIAxes,'Position',[app.XBoxEditField.Value,app.YBoxEditField.Value,100,80],'EdgeColor','r','LineWidth',1)
        end

        % Button pushed function: UnrecordButton
        function UnrecordButtonPushed(app, event)
            app.frameCats(app.currentFrameNum) = 0;
            app.NotRecLabel.Text = 'Not Rec.';
            set(app.fLineHandles(app.currentFrameNum),'color','w')

            % Update the number of categorized frames for each category
            app.Cat1Count.Text = ['(',num2str(nnz(app.frameCats == 1)),')'];
            app.Cat2Count.Text = ['(',num2str(nnz(app.frameCats == 2)),')'];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.ImageCategoryButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageCategoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageCategoryButtonGroupSelectionChanged, true);
            app.ImageCategoryButtonGroup.Title = 'Image Category';
            app.Cat1Button = uiradiobutton(app.ImageCategoryButtonGroup);
            app.Cat1Button.Text = 'No Lick';
            app.Cat1Button.Value = true;
            app.Cat2Button = uiradiobutton(app.ImageCategoryButtonGroup);
            app.Cat2Button.Text = 'Lick';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.NextFrameButton = uibutton(app.UIFigure, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.GoForward1SecButton = uibutton(app.UIFigure, 'push');
            app.GoForward1SecButton.ButtonPushedFcn = createCallbackFcn(app, @GoForward1SecButtonPushed, true);
            app.GoForward1SecButton.Text = 'Go Forward 1 Sec';
            app.PreviousFrameButton = uibutton(app.UIFigure, 'push');
            app.PreviousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFrameButtonPushed, true);
            app.PreviousFrameButton.Text = 'Previous Frame';
            app.GoBack1SecButton = uibutton(app.UIFigure, 'push');
            app.GoBack1SecButton.ButtonPushedFcn = createCallbackFcn(app, @GoBack1SecButtonPushed, true);
            app.GoBack1SecButton.Text = 'Go Back 1 Sec';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NumberofOutlierFramesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofOutlierFramesEditFieldLabel.Text = {'Number of '; 'Outlier Frames'};
            app.NumberofOutlierFramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofOutlierFramesEditField.Limits = [0 200];
            app.NumberofOutlierFramesEditField.Value = 30;
            app.GotoNextOultierButton = uibutton(app.UIFigure, 'push');
            app.GotoNextOultierButton.ButtonPushedFcn = createCallbackFcn(app, @GotoNextOultierButtonPushed, true);
            app.GotoNextOultierButton.Text = 'Go to Next Oultier';
            app.GotoPreviousOutlierButton = uibutton(app.UIFigure, 'push');
            app.GotoPreviousOutlierButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPreviousOutlierButtonPushed, true);
            app.GotoPreviousOutlierButton.Text = 'Go to Previous Outlier';
            app.FrameXYLabel = uilabel(app.UIFigure);
            app.FrameXYLabel.Text = 'Frame X (Y)';
            app.TimeXYLabel = uilabel(app.UIFigure);
            app.TimeXYLabel.Text = 'Time X (Y)';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name: ';
            app.RecordCategorySwitchLabel = uilabel(app.UIFigure);
            app.RecordCategorySwitchLabel.Text = {'Record'; 'Category'};
            app.RecordCategorySwitch = uiswitch(app.UIFigure, 'toggle');
            app.RecordCategorySwitch.ValueChangedFcn = createCallbackFcn(app, @RecordCategorySwitchValueChanged, true);
            app.NumberofFramesSkippedEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFramesSkippedEditFieldLabel.Text = {'Number of'; 'Frames Skipped'};
            app.NumberofFramesSkippedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofFramesSkippedEditField.Value = 1;
            app.ReferenceFrameEditFieldLabel = uilabel(app.UIFigure);
            app.ReferenceFrameEditFieldLabel.Text = 'Reference Frame';
            app.ReferenceFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReferenceFrameEditField.ValueChangedFcn = createCallbackFcn(app, @ReferenceFrameEditFieldValueChanged, true);
            app.ReferenceFrameEditField.Value = 1;
            app.KeepRefFrameButton = uibutton(app.UIFigure, 'push');
            app.KeepRefFrameButton.ButtonPushedFcn = createCallbackFcn(app, @KeepRefFrameButtonPushed, true);
            app.KeepRefFrameButton.Text = 'Keep Ref Frame';
            app.RemoveRefFrameButton = uibutton(app.UIFigure, 'push');
            app.RemoveRefFrameButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRefFrameButtonPushed, true);
            app.RemoveRefFrameButton.Text = 'Remove Ref Frame';
            app.KeptRefFramesDropDownLabel = uilabel(app.UIFigure);
            app.KeptRefFramesDropDownLabel.Text = 'Kept Ref Frames';
            app.KeptRefFramesDropDown = uidropdown(app.UIFigure);
            app.KeptRefFramesDropDown.Items = {'Frame 1'};
            app.KeptRefFramesDropDown.ValueChangedFcn = createCallbackFcn(app, @KeptRefFramesDropDownValueChanged, true);
            app.KeptRefFramesDropDown.Value = 'Frame 1';
            app.XBoxEditFieldLabel = uilabel(app.UIFigure);
            app.XBoxEditFieldLabel.Text = 'X Box';
            app.XBoxEditField = uieditfield(app.UIFigure, 'numeric');
            app.XBoxEditField.Limits = [0 241];
            app.XBoxEditField.RoundFractionalValues = 'on';
            app.XBoxEditField.Value = 80;
            app.YBoxEditFieldLabel = uilabel(app.UIFigure);
            app.YBoxEditFieldLabel.Text = 'Y Box';
            app.YBoxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YBoxEditField.Limits = [0 201];
            app.YBoxEditField.RoundFractionalValues = 'on';
            app.YBoxEditField.Value = 80;
            app.ToggleBoxButton = uibutton(app.UIFigure, 'push');
            app.ToggleBoxButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleBoxButtonPushed, true);
            app.ToggleBoxButton.Text = 'Toggle Box';
            app.NotRecLabel = uilabel(app.UIFigure);
            app.NotRecLabel.Text = 'Not Rec.';
            app.UnrecordButton = uibutton(app.UIFigure, 'push');
            app.UnrecordButton.ButtonPushedFcn = createCallbackFcn(app, @UnrecordButtonPushed, true);
            app.UnrecordButton.Text = 'Unrecord';
            app.Cat1Count = uilabel(app.UIFigure);
            app.Cat1Count.Text = '(0)';
            app.Cat2Count = uilabel(app.UIFigure);
            app.Cat2Count.Text = '(0)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageClassifierVer2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end